classdef Orbital_App_V3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HeatFluxonSatelliteUIFigure    matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        CalculateButton                matlab.ui.control.Button
        OrbitAltitudekmEditFieldLabel  matlab.ui.control.Label
        OrbitAltitudekmEditField       matlab.ui.control.NumericEditField
        InclinationAngledegEditFieldLabel  matlab.ui.control.Label
        InclinationAngledegEditField   matlab.ui.control.NumericEditField
        BetaAngledegEditFieldLabel     matlab.ui.control.Label
        BetaAngledegEditField          matlab.ui.control.NumericEditField
        SimulationTypeDropDownLabel    matlab.ui.control.Label
        SimulationTypeDropDown         matlab.ui.control.DropDown
        StatusLabel                    matlab.ui.control.Label
        StatusTextArea                 matlab.ui.control.TextArea
        SolarFluxWm2EditFieldLabel     matlab.ui.control.Label
        SolarFluxWm2EditField          matlab.ui.control.NumericEditField
        AlbedoConstantEditFieldLabel   matlab.ui.control.Label
        AlbedoConstantEditField        matlab.ui.control.NumericEditField
        ResetGraphButton               matlab.ui.control.Button
        MinimumHeatFluxEditFieldLabel  matlab.ui.control.Label
        MinimumHeatFluxEditField       matlab.ui.control.NumericEditField
        MaximumHeatFluxEditFieldLabel  matlab.ui.control.Label
        MaximumHeatFluxEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        %declaring constants needed for calculations
        rad_earth=6378137;  %mass of earth - m
        Re = 6378137;       %radius of earth - m
        G=6.67408e-11;      %gravitational constant - m^3/kg*s^2
        mu_earth=3.986004418e14; %gravational parameter    - m^3/s^2
        m_earth=5.972e24;       %mass of earth - kg
        J2=1.08262668e-3;         
        e=0;                    %ecentricity of orbit
        epsilon=0.40928;        %        
        planetary_Heating = 236; %W/m^2
    end

    methods (Access = public)
        
        %helper function which calculates the amount of planetary heating
        function planetaryHeatFlux = findIRHeating(app, r_Orbit,t)
            scalingFactor = ((app.rad_earth/(r_Orbit))^2);
            planetaryHeatFlux = scalingFactor * app.planetary_Heating;
            planetaryHeatFlux = repmat(planetaryHeatFlux,1,length(t));
        end
        
        %helper function to calculate the amount of albedo heating (reflected solar flux)
        function albedoHeatFlux = findAlbedoHeating(app,r_Orbit,zenithAngle,SolarFlux,albedoConstant)
            scalingFactor = (app.rad_earth/(r_Orbit))^2;
            albedoHeatFlux = SolarFlux.*albedoConstant.*scalingFactor.*zenithAngle;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %allow user to zoom in on GUI Axes
        zoom(app.UIAxes,'on');
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
        %reset the axis of the graph when the calculate button is pushed
        app.UIAxes.XLimMode = 'auto';
        app.UIAxes.YLimMode = 'auto';
            
        alt = app.OrbitAltitudekmEditField.Value * 1000; %orbit altitude from field -- m
        i = app.InclinationAngledegEditField.Value * (pi/180); %inclination angle from field-- rad
        betaInput = app.BetaAngledegEditField.Value *(pi/180); %beta angle from field
        r_Orbit = alt+app.rad_earth; %total orbital radius -- m
        solarFluxInput = app.SolarFluxWm2EditField.Value;
        albedoConstant = app.AlbedoConstantEditField.Value;
        
        %calcualte orbital velocity and orbital period
        velocity = (sqrt((app.G*app.m_earth)/(alt+app.rad_earth))); % m/s
        orbital_period = (((2*pi*(alt+app.rad_earth))/velocity)/60); %min
        w = velocity/r_Orbit; %rad/s
        
        
        %if the user wants to simulate the satellite orbiting for a time period, several orbital parameter will change over time
        %this loop will execute if the user chooses to orbit for a time period. 
        if strcmp(app.SimulationTypeDropDown.Value, 'Orbit for Time Period')
            app.StatusTextArea.Value = 'Calculating...';
            
            t = (0:1:(365*24*60)-1);        %array of time values for every minute in a year
            gamma= ((0.01721)/(24*60)) .* t; %gamma value at each value of time
            
            %CALCULATION FOR THE ASCENDING NODE
            p=r_Orbit*(1-app.e^2);
            omega_dot=(-3/2)*app.J2*((app.rad_earth/p)^2)*sqrt((app.mu_earth/(r_Orbit^3)))*cos(i); %/s
            omega=(omega_dot*60).*t;
            
            %CALCULATION OF THE BETA ANGLE        
            beta=(asin((cos(gamma).*sin(omega).*sin(i))-(sin(gamma).*cos(app.epsilon).*...
                cos(omega).*sin(i))+(sin(gamma).*sin(app.epsilon).*cos(i))));
            
            %%%%
            %generatres mean anomaly of orbit at each point in time. We want to know the
            %radial distance from that POINT (pericenter). because of this, anytime the value of
            %mean anomaly exceeds 2pi rad, we set it back to zero (meaning one orbit was completed)
            
            n = (2*pi)/(orbital_period); %mean angular motion -- rad/min
            T = 0; %time the body is at pericenter, initally one
            j = 0;
            
            %loop for each value of time, every minute
            for j = t
                %calculate mean anomaly using equation below
                M(j+1)  = n.*(j-T);
                %if mean anomaly is greater then 2pi, meaning one orbit was completed
                %set the mean anomaly back to 0. Change the time the body was at pericenter
                if M(j+1) > (2*pi)
                    M(j+1) = 0;
                    T = j;
                end
            end
            %%%%%
            
            %theta is the half of the total eclipsed angle. This is a function of beta, and changes over time
            Theta = asin(sqrt((1./(cos(beta).^2)).*((app.rad_earth/r_Orbit)^2-(sin(beta).^2))));
            zenithAngle = cos(beta).*cos(M);
            
            phiTop = pi - Theta;    %the angle which the earths eclipse shadow begins
            phiBottom = pi + Theta; %the angle which the earths eclipse shadow ends
            
            %calculate if the satellite is in the sun for each time value. if the satellite is in the sun,
            %the solar flux value is added to the matrix
            for k = t
                %if the mean anomaly is greater than phiTop and less than phiBottom, satellite is in the shadow
                if M(k+1) > phiTop(k+1) && M(k+1) < phiBottom(k+1)
                    SolarFlux(k+1) = 0;
                else
                %otherwise the satellite is in the sun. 
                    SolarFlux(k+1) = solarFluxInput;
                end
            end
            
            %call helper functions to find planetery and albedo heat flux
            planetaryHeatFlux = findIRHeating(app, r_Orbit,t);
            albedoHeatFlux = findAlbedoHeating(app,r_Orbit,zenithAngle,SolarFlux,albedoConstant);
            
            %sum all values of heat flux to get the total hitting the satellite
            totalHeatFlux = SolarFlux + planetaryHeatFlux + albedoHeatFlux;
            
            %plot the heat flux over time
            plot(app.UIAxes,t,totalHeatFlux)

            app.MinimumHeatFluxEditField.Value = min(totalHeatFlux);
            app.MaximumHeatFluxEditField.Value = max(totalHeatFlux);
            
            app.StatusTextArea.Value = 'Ready';         %give a status message to the user   
        end


        %%if the user wants to simulate a single orbit, the beta angle will be "fixed". 
        %%this loop will execute if the fixed beta angle option is selected
        if strcmp(app.SimulationTypeDropDown.Value, 'Fixed Beta Angle')
            app.StatusTextArea.Value = 'Calculating...';        %give a status message to the user
            
            t = (0:1:orbital_period);   %array of time values. each minute within a single orbit
            
            n = (2*pi)/(orbital_period); %mean angular motion -- rad/min
            
            T = 0;
            for j = t
                %calculate mean anomaly using equation below
                M(j+1)  = n.*(j-T);
                %if mean anomaly is greater then 2pi, meaning one orbit was completed
                %set the mean anomaly back to 0. Change the time the body was at pericenter
                if M(j+1) > (2*pi)
                    M(j+1) = 0;
                    T = j;
                end
            end
            
            %theta is one half of the total eclipsed angle
            Theta = (asin(sqrt((1./(cos(betaInput).^2)).*((app.rad_earth/r_Orbit)^2-(sin(betaInput).^2)))));
            zenithAngle = cos(betaInput).*cos(M);
            
            phiTop = pi - Theta;        %angle at which shadow starts
            phiBottom = pi + Theta;     %angle where the shadow ends
            
            %calculate if the satellite is in the sun for each time value. if the satellite is in the sun,
            %the solar flux value is added to the matrix
            for k = t
                %if the mean anomaly is greater than phiTop and less than phiBottom, satellite is in the shadow
                if M(k+1) > phiTop && M(k+1) < phiBottom
                    SolarFlux(k+1) = 0;
                %if it isn't in the shadow, the satellite is in the sun.
                else
                    SolarFlux(k+1) = solarFluxInput;
                end
            end
            
            %call helper to find planetary and albedo heat flux
            planetaryHeatFlux = findIRHeating(app, r_Orbit,t);
            albedoHeatFlux = findAlbedoHeating(app,r_Orbit,zenithAngle,SolarFlux,albedoConstant);
            
            %calculate total heat flux by summing all heat flux's
            totalHeatFlux = SolarFlux + planetaryHeatFlux + albedoHeatFlux;
            
            %plot the total heat flux over time
            plot(app.UIAxes,t,totalHeatFlux)
            
            %display max and min values of heat flux
            app.MinimumHeatFluxEditField.Value = min(totalHeatFlux);
            app.MaximumHeatFluxEditField.Value = max(totalHeatFlux);
            
            app.StatusTextArea.Value = 'Ready';     %give a status message to the user
            
        end
        end

        % Value changed function: OrbitAltitudekmEditField
        function OrbitAltitudekmEditFieldValueChanged(app, event)
            %entry field for orbital altitude
            Value = app.OrbitAltitudekmEditField.Value;
        end

        % Value changed function: InclinationAngledegEditField
        function InclinationAngledegEditFieldValueChanged(app, event)
            %entry field for orbit inclination angle
            Value = app.InclinationAngledegEditField.Value;
        end

        % Value changed function: BetaAngledegEditField
        function BetaAngledegEditFieldValueChanged(app, event)
            %entry field for fixed beta angle
            Value = app.BetaAngledegEditField.Value;
            
        end

        % Value changed function: SimulationTypeDropDown
        function simulationType(app, event)
            %allows user to select the simulation type
            value = app.SimulationTypeDropDown.Value;
            
            %enable for disable the beta angle edit field depending on simulation type
            if strcmp(value,'Orbit for Time Period')
                app.BetaAngledegEditField.Editable = 'off';
            else
                app.BetaAngledegEditField.Editable = 'on';
            end
            
        end

        % Value changed function: StatusTextArea
        function statusMessage(app, event)
            %gives user a status message
            value = app.StatusTextArea.Value;
            
        end

        % Value changed function: SolarFluxWm2EditField
        function SolarFluxEditField(app, event)
            %allows user to input the solar flux (should be fairly standard, this was
            %added to allow a user to simulate incase of a solar flare or something similar).
            value = app.SolarFluxWm2EditField.Value;
            
        end

        % Value changed function: AlbedoConstantEditField
        function AlbedoConstantEditFieldValueChanged(app, event)
            %allows user to enter the albedo constant (fairly consistent around .3, but can vary)
            value = app.AlbedoConstantEditField.Value;
            
        end

        % Button pushed function: ResetGraphButton
        function ResetGraphButtonPushed(app, event)
            %if the graph is zoomed, the button click will reset the axes
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HeatFluxonSatelliteUIFigure = uifigure;
            app.HeatFluxonSatelliteUIFigure.Name = 'Heat Flux on Satellite';
            app.UIAxes = uiaxes(app.HeatFluxonSatelliteUIFigure);
            title(app.UIAxes, 'Heat Flux vs. Time')
            xlabel(app.UIAxes, 'Time (min)')
            ylabel(app.UIAxes, 'Heat Flux (W/m^2)')
            app.CalculateButton = uibutton(app.HeatFluxonSatelliteUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate ';
            app.OrbitAltitudekmEditFieldLabel = uilabel(app.HeatFluxonSatelliteUIFigure);
            app.OrbitAltitudekmEditFieldLabel.Text = 'Orbit Altitude (km)';
            app.OrbitAltitudekmEditField = uieditfield(app.HeatFluxonSatelliteUIFigure, 'numeric');
            app.OrbitAltitudekmEditField.Limits = [0 2000];
            app.OrbitAltitudekmEditField.ValueChangedFcn = createCallbackFcn(app, @OrbitAltitudekmEditFieldValueChanged, true);
            app.OrbitAltitudekmEditField.Value = 408;
            app.InclinationAngledegEditFieldLabel = uilabel(app.HeatFluxonSatelliteUIFigure);
            app.InclinationAngledegEditFieldLabel.Text = 'Inclination Angle (deg)';
            app.InclinationAngledegEditField = uieditfield(app.HeatFluxonSatelliteUIFigure, 'numeric');
            app.InclinationAngledegEditField.Limits = [0 90];
            app.InclinationAngledegEditField.ValueChangedFcn = createCallbackFcn(app, @InclinationAngledegEditFieldValueChanged, true);
            app.InclinationAngledegEditField.Value = 51.6;
            app.BetaAngledegEditFieldLabel = uilabel(app.HeatFluxonSatelliteUIFigure);
            app.BetaAngledegEditFieldLabel.Text = 'Beta Angle (deg)';
            app.BetaAngledegEditField = uieditfield(app.HeatFluxonSatelliteUIFigure, 'numeric');
            app.BetaAngledegEditField.Limits = [0 90];
            app.BetaAngledegEditField.ValueChangedFcn = createCallbackFcn(app, @BetaAngledegEditFieldValueChanged, true);
            app.BetaAngledegEditField.Editable = 'off';
            app.BetaAngledegEditField.Value = 45;
            app.SimulationTypeDropDownLabel = uilabel(app.HeatFluxonSatelliteUIFigure);
            app.SimulationTypeDropDownLabel.Text = 'Simulation Type';
            app.SimulationTypeDropDown = uidropdown(app.HeatFluxonSatelliteUIFigure);
            app.SimulationTypeDropDown.Items = {'Fixed Beta Angle', 'Orbit for Time Period'};
            app.SimulationTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @simulationType, true);
            app.SimulationTypeDropDown.Value = 'Orbit for Time Period';
            app.StatusLabel = uilabel(app.HeatFluxonSatelliteUIFigure);
            app.StatusLabel.Text = 'Status:';
            app.StatusTextArea = uitextarea(app.HeatFluxonSatelliteUIFigure);
            app.StatusTextArea.ValueChangedFcn = createCallbackFcn(app, @statusMessage, true);
            app.StatusTextArea.Editable = 'off';
            app.StatusTextArea.Value = {'Program Startup Successful'};
            app.SolarFluxWm2EditFieldLabel = uilabel(app.HeatFluxonSatelliteUIFigure);
            app.SolarFluxWm2EditFieldLabel.Text = 'Solar Flux (W/m^2)';
            app.SolarFluxWm2EditField = uieditfield(app.HeatFluxonSatelliteUIFigure, 'numeric');
            app.SolarFluxWm2EditField.Limits = [500 2500];
            app.SolarFluxWm2EditField.ValueChangedFcn = createCallbackFcn(app, @SolarFluxEditField, true);
            app.SolarFluxWm2EditField.Value = 1377;
            app.AlbedoConstantEditFieldLabel = uilabel(app.HeatFluxonSatelliteUIFigure);
            app.AlbedoConstantEditFieldLabel.Text = 'Albedo Constant';
            app.AlbedoConstantEditField = uieditfield(app.HeatFluxonSatelliteUIFigure, 'numeric');
            app.AlbedoConstantEditField.Limits = [0 1];
            app.AlbedoConstantEditField.ValueChangedFcn = createCallbackFcn(app, @AlbedoConstantEditFieldValueChanged, true);
            app.AlbedoConstantEditField.Value = 0.3;
            app.ResetGraphButton = uibutton(app.HeatFluxonSatelliteUIFigure, 'push');
            app.ResetGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ResetGraphButtonPushed, true);
            app.ResetGraphButton.Text = 'Reset Graph';
            app.MinimumHeatFluxEditFieldLabel = uilabel(app.HeatFluxonSatelliteUIFigure);
            app.MinimumHeatFluxEditFieldLabel.Text = 'Minimum Heat Flux: ';
            app.MinimumHeatFluxEditField = uieditfield(app.HeatFluxonSatelliteUIFigure, 'numeric');
            app.MinimumHeatFluxEditField.Editable = 'off';
            app.MaximumHeatFluxEditFieldLabel = uilabel(app.HeatFluxonSatelliteUIFigure);
            app.MaximumHeatFluxEditFieldLabel.Text = 'Maximum Heat Flux';
            app.MaximumHeatFluxEditField = uieditfield(app.HeatFluxonSatelliteUIFigure, 'numeric');
            app.MaximumHeatFluxEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Orbital_App_V3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HeatFluxonSatelliteUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HeatFluxonSatelliteUIFigure)
        end
    end
end